classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        TabGroup              matlab.ui.container.TabGroup
        Tab                   matlab.ui.container.Tab
        Label_3               matlab.ui.control.Label
        Gauge                 matlab.ui.control.NinetyDegreeGauge
        Label_2               matlab.ui.control.Label
        sendInfo              matlab.ui.control.TextArea
        sendBtn               matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        recvInfo              matlab.ui.control.TextArea
        recorder              matlab.ui.control.StateButton
        Tab_2                 matlab.ui.container.Tab
        tcpLampLabel          matlab.ui.control.Label
        tcpLamp               matlab.ui.control.Lamp
        mode1Lamp             matlab.ui.control.Lamp
        mode2Lamp             matlab.ui.control.Lamp
        mode3Lamp             matlab.ui.control.Lamp
        f0EditFieldLabel      matlab.ui.control.Label
        chirpf0EditField      matlab.ui.control.NumericEditField
        f1EditFieldLabel      matlab.ui.control.Label
        chirpf1EditField      matlab.ui.control.NumericEditField
        TEditFieldLabel       matlab.ui.control.Label
        chirpTEditField       matlab.ui.control.NumericEditField
        IPLabel               matlab.ui.control.Label
        IPEditField           matlab.ui.control.EditField
        EditFieldLabel        matlab.ui.control.Label
        portEditField         matlab.ui.control.NumericEditField
        dAEditFieldLabel      matlab.ui.control.Label
        dAEditField           matlab.ui.control.NumericEditField
        mLabel                matlab.ui.control.Label
        dBEditFieldLabel      matlab.ui.control.Label
        dBEditField           matlab.ui.control.NumericEditField
        mLabel_2              matlab.ui.control.Label
        Label_4               matlab.ui.control.Label
        distEditField         matlab.ui.control.NumericEditField
        mLabel_3              matlab.ui.control.Label
        beepbeepAbtn          matlab.ui.control.StateButton
        beepbeepBbtn          matlab.ui.control.StateButton
        methodEditFieldLabel  matlab.ui.control.Label
        methodEditField       matlab.ui.control.EditField
        RightPanel            matlab.ui.container.Panel
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        myRecorder  audiorecorder; % 录音
        t timer; % 录音计时器
        beepbeep_mode = 0; % beepbeep的传输内容
        beepbeep_type = 'A'; % beepbeep是设备A或B
        preamble_bit_num = 8; % 前导码长度
        preamble_code = [0 1 0 1 0 1 1 0]; % 前导码
        packetLenLen = 8; % 包长度码的长度
        mode = 0; % FSK接受的阶段
        t1 = 0;
        t3 = 0;
        
        chirp_signal = chirp(0:1/48000:0.1, 6000, 0.1, 8000);
        chirp_f0 = 6000;
        chirp_f1 = 8000;
        chirp_T = 0.1;
        chirp_method;
        
        
        pStart = 0; % 当前包的开头点
        
        f0 = 6000; % 低频率
        f1 = 8000; % 高频率
        duration = 0.025; % 间隔
        Fs = 48000; % 采样率
        
        data_recv = -1; % tcp server 收到的信息
        ip; % tcp传输的ip地址
        port; % tcp传输的目标端口
        
        dA = 0; % 设备A的麦克风与扬声器的距离
        dB = 0; % 设备B的麦克风与扬声器的距离
        
        t_server;
        t_client;
    end
    
    properties (Access = private)
        
    end
    
    methods (Access = private)
        
        function recorderTimerFcn(app)
            myRecording = getaudiodata(app.myRecorder);
            plot(app.UIAxes, myRecording(end-100:end));
        end
    end
    
    methods (Access = public)
        
        function demod_packet(app)
            myRecording = getaudiodata(app.myRecorder);
            audiowrite('fsk.wav',myRecording, app.Fs);
            app.pStart = 0;
            str = decode (myRecording,app.preamble_code);
            app.recvInfo.Value = str;
            return
        end
        
        function demod_beepbeep(app)
            myRecording = getaudiodata(app.myRecorder);
            audiowrite('beepbeep.wav', myRecording, app.Fs);
            
            app.t1 = position(myRecording, app.chirp_signal, length(app.chirp_signal), app.chirp_f0, app.chirp_f1);            
            if app.t1 == -1
                'beepbeep t1 解析出错'
                return
            end
            app.t3 = position(myRecording(app.t1 + length(app.chirp_signal): end), app.chirp_signal, length(app.chirp_signal), app.chirp_f0, app.chirp_f1);
            if app.t3 == -1
                'beepbeep t3 解析出错'
                return
            end
            app.t3 = app.t3 + app.t1 + length(app.chirp_signal);
            %plot_corr(myRecording,app.chirp_signal, length(app.chirp_signal),app.t1,app.t3,app.chirp_f0, app.chirp_f1);
        end
        
        function get_chirp_args(app)
            app.chirp_f0 = app.chirpf0EditField.Value;
            app.chirp_f1 = app.chirpf1EditField.Value;
            app.chirp_T = app.chirpTEditField.Value;
            app.chirp_method = app.methodEditField.Value;
            app.chirp_signal = chirp(0:1/app.Fs:app.chirp_T, app.chirp_f0, app.chirp_T, app.chirp_f1, app.chirp_method);
            app.chirp_signal = [app.chirp_signal; zeros(1, length(app.chirp_signal))];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: recorder
        function recorderValueChanged(app, event)
            
            value = app.recorder.Value;
            if  value == 1
                app.t= timer('Period',0.1,'ExecutionMode',"fixedRate");
                app.myRecorder = audiorecorder(app.Fs, 24, 1);
                record(app.myRecorder);
                app.t.StartDelay = 0.6;
                app.t.Period = 0.1;
                app.t.TimerFcn=@(~,~)recorderTF(app);
                start(app.t);
                app.mode = 0;
                app.mode1Lamp.Color = [0 1 0];
                
                app.recvInfo.Value = '';
                
            elseif  value == 0
                stop(app.t);
                delete(app.t);
                pause(app.myRecorder);
                %play(app.myRecorder);
                audiowrite('test.wav',getaudiodata(app.myRecorder),app.Fs);
                demod_packet(app);
                
            end
        end

        % Value changed function: beepbeepAbtn
        function beepbeepAbtnValueChanged(app, event)
            value = app.beepbeepAbtn.Value;
            
            if value == 1
                get_chirp_args(app);
                app.ip = app.IPEditField.Value;
                app.port = app.portEditField.Value;
                app.dA = app.dAEditField.Value;
                app.dB = app.dBEditField.Value;
                
                app.distEditField.Value = 0;
                
                app.t_server = tcpip('0.0.0.0', app.port, 'NetworkRole', 'server');
                fopen(app.t_server);
                app.tcpLamp.Color = [0 1 0];
                
                app.myRecorder = audiorecorder(app.Fs,24,1);
                record(app.myRecorder);
                app.mode1Lamp.Color = [0 1 0];
                
                pause(0.2);
                sound(app.chirp_signal, app.Fs);
                pause(0.5 + app.chirp_T);
                
                fwrite(app.t_server, '-1', 'int');
                
                while(true)
                    if app.t_server.BytesAvailable > 0
                        fread(app.t_server, app.t_server.BytesAvailable / 4, 'int');
                        break;
                    end
                end
                
                pause(app.myRecorder);
                app.demod_beepbeep();
                
                tB_sub = 0;
                while(true)
                    if app.t_server.BytesAvailable > 0
                        tB_sub = fread(app.t_server, app.t_server.BytesAvailable / 4, 'int');
                        break;
                    end
                end
                

                app
                tB_sub
                dist = 0.5 * 340 * ((app.t3 - app.t1) - tB_sub) / app.Fs + app.dA + app.dB
                app.distEditField.Value = dist;
                
                
                app.mode1Lamp.Color = [1 0 0];
                app.mode2Lamp.Color = [1 0 0];
                app.mode3Lamp.Color = [1 0 0];
                app.tcpLamp.Color = [1 0 0];
                fclose(app.t_server);
                app.beepbeepAbtn.Value = 0;
                
                
            elseif value == 0
                pause(app.myRecorder);
                fclose(app.t_server);
                app.mode1Lamp.Color = [1 0 0];
                app.mode2Lamp.Color = [1 0 0];
                app.mode3Lamp.Color = [1 0 0];
                app.tcpLamp.Color = [1 0 0];
            end
        end

        % Value changed function: beepbeepBbtn
        function beepbeepBbtnValueChanged(app, event)
            value = app.beepbeepBbtn.Value;
            if value == 1
                get_chirp_args(app);
                app.mode1Lamp.Color = [0 1 0];
                app.ip = app.IPEditField.Value;
                app.port = app.portEditField.Value;
                
                app.distEditField.Value = 0;
                
                app.myRecorder = audiorecorder(app.Fs,24,1);
                record(app.myRecorder);
                app.mode1Lamp.Color = [0 1 0];
                pause(0.5);
                
                app.t_client = tcpip(app.ip, app.port, 'NetworkRole', 'client');
                fopen(app.t_client);
                app.tcpLamp.Color = [0 1 0];


                while(true)
                    if app.t_client.BytesAvailable > 0
                        fread(app.t_client, app.t_client.BytesAvailable / 4, 'int');
                        break;
                    end
                end
                app.mode2Lamp.Color = [0 1 0];
                
                sound(app.chirp_signal, app.Fs);
                pause(0.5 + app.chirp_T);
                
                app.mode3Lamp.Color = [0 1 0];
                pause(app.myRecorder);
                
                fwrite(app.t_client, -1, "int");
                
                app.demod_beepbeep();
                app
                fwrite(app.t_client, app.t3-app.t1, "int");
                
                fclose(app.t_client);
                
                app.mode1Lamp.Color = [1 0 0];
                app.mode2Lamp.Color = [1 0 0];
                app.mode3Lamp.Color = [1 0 0];
                app.tcpLamp.Color = [1 0 0];

                
            elseif value == 0
                pause(app.myRecorder);
                fclose(app.t_client);
                app.mode1Lamp.Color = [1 0 0];
                app.mode2Lamp.Color = [1 0 0];
                app.mode3Lamp.Color = [1 0 0];
                app.tcpLamp.Color = [1 0 0];
            end
        end

        % Button pushed function: sendBtn
        function sendBtnButtonPushed(app, event)
            %app.sendInfo.Value
            signal = encode(char(app.sendInfo.Value), app.preamble_code);
            signal = [signal; zeros(1, length(signal))];
            sound(signal, app.Fs);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {522, 522};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {248, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '收发信号';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '信号强度';
            app.Gauge = uigauge(app.Tab, 'ninetydegree');
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '发送信号';
            app.sendInfo = uitextarea(app.Tab);
            app.sendBtn = uibutton(app.Tab, 'push');
            app.sendBtn.ButtonPushedFcn = createCallbackFcn(app, @sendBtnButtonPushed, true);
            app.sendBtn.Text = '发送';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '接收信号';
            app.recvInfo = uitextarea(app.Tab);
            app.recorder = uibutton(app.Tab, 'state');
            app.recorder.ValueChangedFcn = createCallbackFcn(app, @recorderValueChanged, true);
            app.recorder.Text = '接受';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '声波测距';
            app.tcpLampLabel = uilabel(app.Tab_2);
            app.tcpLampLabel.Text = '建立起tcp连接';
            app.tcpLamp = uilamp(app.Tab_2);
            app.mode1Lamp = uilamp(app.Tab_2);
            app.mode2Lamp = uilamp(app.Tab_2);
            app.mode3Lamp = uilamp(app.Tab_2);
            app.f0EditFieldLabel = uilabel(app.Tab_2);
            app.f0EditFieldLabel.Text = 'f0';
            app.chirpf0EditField = uieditfield(app.Tab_2, 'numeric');
            app.chirpf0EditField.Value = 3000;
            app.f1EditFieldLabel = uilabel(app.Tab_2);
            app.f1EditFieldLabel.Text = 'f1';
            app.chirpf1EditField = uieditfield(app.Tab_2, 'numeric');
            app.chirpf1EditField.Value = 6000;
            app.TEditFieldLabel = uilabel(app.Tab_2);
            app.TEditFieldLabel.Text = 'T';
            app.chirpTEditField = uieditfield(app.Tab_2, 'numeric');
            app.chirpTEditField.Value = 0.1;
            app.IPLabel = uilabel(app.Tab_2);
            app.IPLabel.Text = 'IP地址';
            app.IPEditField = uieditfield(app.Tab_2, 'text');
            app.IPEditField.Value = '172.20.10.9';
            app.EditFieldLabel = uilabel(app.Tab_2);
            app.EditFieldLabel.Text = '端口';
            app.portEditField = uieditfield(app.Tab_2, 'numeric');
            app.portEditField.Value = 30000;
            app.dAEditFieldLabel = uilabel(app.Tab_2);
            app.dAEditFieldLabel.Text = 'dA';
            app.dAEditField = uieditfield(app.Tab_2, 'numeric');
            app.mLabel = uilabel(app.Tab_2);
            app.mLabel.Text = 'm';
            app.dBEditFieldLabel = uilabel(app.Tab_2);
            app.dBEditFieldLabel.Text = 'dB';
            app.dBEditField = uieditfield(app.Tab_2, 'numeric');
            app.mLabel_2 = uilabel(app.Tab_2);
            app.mLabel_2.Text = 'm';
            app.Label_4 = uilabel(app.Tab_2);
            app.Label_4.Text = '距离为';
            app.distEditField = uieditfield(app.Tab_2, 'numeric');
            app.mLabel_3 = uilabel(app.Tab_2);
            app.mLabel_3.Text = 'm';
            app.beepbeepAbtn = uibutton(app.Tab_2, 'state');
            app.beepbeepAbtn.ValueChangedFcn = createCallbackFcn(app, @beepbeepAbtnValueChanged, true);
            app.beepbeepAbtn.Text = '测距A';
            app.beepbeepBbtn = uibutton(app.Tab_2, 'state');
            app.beepbeepBbtn.ValueChangedFcn = createCallbackFcn(app, @beepbeepBbtnValueChanged, true);
            app.beepbeepBbtn.Text = '测距B';
            app.methodEditFieldLabel = uilabel(app.Tab_2);
            app.methodEditFieldLabel.Text = 'method';
            app.methodEditField = uieditfield(app.Tab_2, 'text');
            app.methodEditField.Value = 'linear';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end